classdef backtestMVPApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        SceglilaSerietemporaledaAnalizzareButtonGroup  matlab.ui.container.ButtonGroup
        SP500Button                    matlab.ui.control.RadioButton
        DowJonesButton                 matlab.ui.control.RadioButton
        CSVDaInvestingButton           matlab.ui.control.RadioButton
        ScegliCSVscaricatodaInvestingUSAButton  matlab.ui.control.Button
        OppureLabel                    matlab.ui.control.Label
        ParametriStrategiaRSIPanel     matlab.ui.container.Panel
        PeriodiRSIEditFieldLabel       matlab.ui.control.Label
        PeriodiRSIEditField            matlab.ui.control.NumericEditField
        BandaInferioreEditFieldLabel   matlab.ui.control.Label
        BandaInferioreEditField        matlab.ui.control.NumericEditField
        BandaSuperioreEditFieldLabel   matlab.ui.control.Label
        BandaSuperioreEditField        matlab.ui.control.NumericEditField
        SceglilaStrategiaDaUsareButtonGroup  matlab.ui.container.ButtonGroup
        RSISorvracompratoSovravendutoButton  matlab.ui.control.RadioButton
        MedieMobiliButton              matlab.ui.control.RadioButton
        ParametriStrategia2MedieMobiliPanel  matlab.ui.container.Panel
        PeriodiMAVelocemenoperiodiEditFieldLabel  matlab.ui.control.Label
        PeriodiMAVelocemenoperiodiEditField  matlab.ui.control.NumericEditField
        PeriodiMAlentapiperiodiEditFieldLabel  matlab.ui.control.Label
        PeriodiMAlentapiperiodiEditField  matlab.ui.control.NumericEditField
        OttimizzarelastrategiaButtonGroup  matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        SButton                        matlab.ui.control.RadioButton
        PortafoglioInizialeEditFieldLabel  matlab.ui.control.Label
        PortafoglioInizialeEditField   matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        ProfitLossLabel                matlab.ui.control.Label
        InteresseLabel                 matlab.ui.control.Label
        VolatilitPortafoglioLabel      matlab.ui.control.Label
        QuantitdiOrdiniLabel           matlab.ui.control.Label
        MassimoDrawDownLabel           matlab.ui.control.Label
        QuantitordinidiBuyLabel        matlab.ui.control.Label
        profitLossdiOrdiniinBuyLabel   matlab.ui.control.Label
        QuantitordiniinSellLabel       matlab.ui.control.Label
        SharpeRatioLabel               matlab.ui.control.Label
        profitLossdiOrdiniinSellLabel  matlab.ui.control.Label
        MissingDataLabel               matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CustomCSVPath % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% get inputs
        % initialPortfolio
        initialPortfolioValue = app.PortafoglioInizialeEditField.Value;
        
        %timeseries
        timeseriesName = app.SceglilaSerietemporaledaAnalizzareButtonGroup.SelectedObject.Text
        if timeseriesName =="SP500"
            timeseriesPath = 'inputTimeseries/S&P 500.csv';
        elseif timeseriesName =="Dow Jones"
            timeseriesPath = 'inputTimeseries/Dow Jones.csv';
        elseif timeseriesName =="CSV Da Investing"
            timeseriesPath = app.CustomCSVPath;
        end
        
        % Strategy 
        strategyNameFromButtons = app.SceglilaStrategiaDaUsareButtonGroup.SelectedObject.Text
        if strategyNameFromButtons =="RSI Sorvracomprato/Sovravenduto"
            strategyName = 'single RSI Strategy';
        elseif strategyNameFromButtons =="2 Medie Mobili"
            strategyName = 'two Moving Average Strategy';
        end
        
        %Optimize or Not
        optimizeFromButtons = app.OttimizzarelastrategiaButtonGroup.SelectedObject.Text
        if optimizeFromButtons =="Sì"
            optimize = true;
        elseif optimizeFromButtons =="No"
            optimize = false;
        end
        
        % input Parameters
        if strategyNameFromButtons =="RSI Sorvracomprato/Sovravenduto"
            rsiPeriods = app.PeriodiRSIEditField.Value;
            lowerBand = app.BandaInferioreEditField.Value;
            upperBand = app.BandaSuperioreEditField.Value;
            inputParameters = [rsiPeriods, lowerBand, upperBand];
        elseif strategyNameFromButtons =="2 Medie Mobili"
            fastMA = app.PeriodiMAVelocemenoperiodiEditField.Value;
            slowMA = app.PeriodiMAlentapiperiodiEditField.Value;
            inputParameters = [fastMA; slowMA];
        end
        [portfolio,validatedData] = init(initialPortfolioValue, timeseriesPath, strategyName, optimize, inputParameters)
        plot(app.UIAxes_3,...
        datetime(validatedData.dates, 'ConvertFrom', 'datenum', 'Format', 'dd-MM-yy hh:mm:ss'), validatedData.prices)
        
        plot(app.UIAxes,...
        datetime(portfolio.valueHistory(:,1), 'ConvertFrom', 'datenum', 'Format', 'dd-MM-yy hh:mm:ss'), portfolio.valueHistory(:,2))
        
        bar(app.UIAxes_2,...
        datetime(validatedData.dates, 'ConvertFrom', 'datenum', 'Format', 'dd-MM-yy hh:mm:ss'), validatedData.returns)


        %% write output parameters
            app.Label_3.Text = num2str(portfolio.analytics.profitLoss);
        app.Label_4.Text = num2str(portfolio.analytics.interest);
        app.Label_5.Text = num2str(portfolio.analytics.volatility);
        app.Label_6.Text = num2str(portfolio.analytics.amountOfOrders);
        app.Label_8.Text = num2str(portfolio.analytics.percentageMaxDrawDown);
        app.Label_9.Text = num2str(portfolio.analytics.amountOfBuyOrders);
        app.Label_10.Text = num2str(portfolio.analytics.profitLossBuyOrders);
        app.Label_11.Text = num2str(portfolio.analytics.amountOfSellOrders);
        app.Label_12.Text = num2str(portfolio.analytics.profitLossSellOrders);
        app.Label_7.Text = num2str(portfolio.analytics.sharpeRatio);
        app.Label_13.Text = num2str(validatedData.missingData);
        if optimize == true
            if  strategyNameFromButtons =="RSI Sorvracomprato/Sovravenduto"
                app.PeriodiRSIEditField.Value = portfolio.strategy.periods;
                app.BandaInferioreEditField.Value = portfolio.strategy.lowerBand;
                app.BandaSuperioreEditField.Value =portfolio.strategy.upperBand;
            elseif strategyNameFromButtons =="2 Medie Mobili"
                app.PeriodiMAVelocemenoperiodiEditField.Value = portfolio.strategy.MA1.periods; 
                app.PeriodiMAlentapiperiodiEditField.Value = portfolio.strategy.MA2.periods;
            end
        end
        end

        % Button pushed function: 
        % ScegliCSVscaricatodaInvestingUSAButton
        function ScegliCSVscaricatodaInvestingUSAButtonPushed(app, event)
            [file,path] = uigetfile('*.csv')  %open a csv file
            if not(file==0) 
                app.Label_2.Text = [file];
                app.CustomCSVPath = strcat(path,file);
                app.CSVDaInvestingButton.Value =1;
            end
        end

        % Selection changed function: 
        % OttimizzarelastrategiaButtonGroup
        function OttimizzarelastrategiaButtonGroupSelectionChanged(app, event)
            selectedButton = app.OttimizzarelastrategiaButtonGroup.SelectedObject;
            selection  = selectedButton.Text;
            if selection == "No"
                app.Label.Enable ='off';
            elseif selection =="Sì"
                app.Label.Enable ='on';
            end
        end

        % Selection changed function: 
        % SceglilaStrategiaDaUsareButtonGroup
        function SceglilaStrategiaDaUsareButtonGroupSelectionChanged(app, event)
            selectedButton = app.SceglilaStrategiaDaUsareButtonGroup.SelectedObject;
            selection  = selectedButton.Text;
            if selection == "RSI Sorvracomprato/Sovravenduto"
                app.ParametriStrategiaRSIPanel.Enable="on";
                app.ParametriStrategia2MedieMobiliPanel.Enable="off";
            elseif selection == "2 Medie Mobili"
                app.ParametriStrategiaRSIPanel.Enable="off";
                app.ParametriStrategia2MedieMobiliPanel.Enable="on";
            end
        end

        % Selection changed function: 
        % SceglilaSerietemporaledaAnalizzareButtonGroup
        function SceglilaSerietemporaledaAnalizzareButtonGroupSelectionChanged(app, event)
            selectedButton = app.SceglilaSerietemporaledaAnalizzareButtonGroup.SelectedObject;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SceglilaSerietemporaledaAnalizzareButtonGroup = uibuttongroup(app.UIFigure);
            app.SceglilaSerietemporaledaAnalizzareButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SceglilaSerietemporaledaAnalizzareButtonGroupSelectionChanged, true);
            app.SceglilaSerietemporaledaAnalizzareButtonGroup.Title = 'Scegli la Serie temporale da Analizzare';
            app.SP500Button = uiradiobutton(app.SceglilaSerietemporaledaAnalizzareButtonGroup);
            app.SP500Button.Text = 'SP500';
            app.SP500Button.Value = true;
            app.DowJonesButton = uiradiobutton(app.SceglilaSerietemporaledaAnalizzareButtonGroup);
            app.DowJonesButton.Text = 'Dow Jones';
            app.CSVDaInvestingButton = uiradiobutton(app.SceglilaSerietemporaledaAnalizzareButtonGroup);
            app.CSVDaInvestingButton.Enable = 'off';
            app.CSVDaInvestingButton.Text = 'CSV Da Investing';
            app.ScegliCSVscaricatodaInvestingUSAButton = uibutton(app.SceglilaSerietemporaledaAnalizzareButtonGroup, 'push');
            app.ScegliCSVscaricatodaInvestingUSAButton.ButtonPushedFcn = createCallbackFcn(app, @ScegliCSVscaricatodaInvestingUSAButtonPushed, true);
            app.ScegliCSVscaricatodaInvestingUSAButton.Text = 'Scegli CSV scaricato da Investing USA';
            app.OppureLabel = uilabel(app.SceglilaSerietemporaledaAnalizzareButtonGroup);
            app.OppureLabel.Text = 'Oppure';
            app.ParametriStrategiaRSIPanel = uipanel(app.UIFigure);
            app.ParametriStrategiaRSIPanel.Title = 'Parametri Strategia RSI';
            app.PeriodiRSIEditFieldLabel = uilabel(app.ParametriStrategiaRSIPanel);
            app.PeriodiRSIEditFieldLabel.Text = 'Periodi RSI';
            app.PeriodiRSIEditField = uieditfield(app.ParametriStrategiaRSIPanel, 'numeric');
            app.PeriodiRSIEditField.Limits = [0 Inf];
            app.PeriodiRSIEditField.Value = 8;
            app.BandaInferioreEditFieldLabel = uilabel(app.ParametriStrategiaRSIPanel);
            app.BandaInferioreEditFieldLabel.Text = 'Banda Inferiore';
            app.BandaInferioreEditField = uieditfield(app.ParametriStrategiaRSIPanel, 'numeric');
            app.BandaInferioreEditField.Limits = [0 100];
            app.BandaInferioreEditField.Value = 30;
            app.BandaSuperioreEditFieldLabel = uilabel(app.ParametriStrategiaRSIPanel);
            app.BandaSuperioreEditFieldLabel.Text = 'Banda Superiore';
            app.BandaSuperioreEditField = uieditfield(app.ParametriStrategiaRSIPanel, 'numeric');
            app.BandaSuperioreEditField.Limits = [0 100];
            app.BandaSuperioreEditField.Value = 70;
            app.SceglilaStrategiaDaUsareButtonGroup = uibuttongroup(app.UIFigure);
            app.SceglilaStrategiaDaUsareButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SceglilaStrategiaDaUsareButtonGroupSelectionChanged, true);
            app.SceglilaStrategiaDaUsareButtonGroup.Title = 'Scegli la Strategia Da Usare';
            app.RSISorvracompratoSovravendutoButton = uiradiobutton(app.SceglilaStrategiaDaUsareButtonGroup);
            app.RSISorvracompratoSovravendutoButton.Text = 'RSI Sorvracomprato/Sovravenduto';
            app.RSISorvracompratoSovravendutoButton.Value = true;
            app.MedieMobiliButton = uiradiobutton(app.SceglilaStrategiaDaUsareButtonGroup);
            app.MedieMobiliButton.Text = '2 Medie Mobili';
            app.ParametriStrategia2MedieMobiliPanel = uipanel(app.UIFigure);
            app.ParametriStrategia2MedieMobiliPanel.Enable = 'off';
            app.ParametriStrategia2MedieMobiliPanel.Title = 'Parametri Strategia 2 Medie Mobili';
            app.PeriodiMAVelocemenoperiodiEditFieldLabel = uilabel(app.ParametriStrategia2MedieMobiliPanel);
            app.PeriodiMAVelocemenoperiodiEditFieldLabel.Text = {'Periodi MA Veloce '; '(meno periodi)'};
            app.PeriodiMAVelocemenoperiodiEditField = uieditfield(app.ParametriStrategia2MedieMobiliPanel, 'numeric');
            app.PeriodiMAVelocemenoperiodiEditField.Limits = [0 Inf];
            app.PeriodiMAVelocemenoperiodiEditField.Value = 4;
            app.PeriodiMAlentapiperiodiEditFieldLabel = uilabel(app.ParametriStrategia2MedieMobiliPanel);
            app.PeriodiMAlentapiperiodiEditFieldLabel.Text = {'Periodi MA lenta'; '(più periodi)'};
            app.PeriodiMAlentapiperiodiEditField = uieditfield(app.ParametriStrategia2MedieMobiliPanel, 'numeric');
            app.PeriodiMAlentapiperiodiEditField.Limits = [0 Inf];
            app.PeriodiMAlentapiperiodiEditField.Value = 8;
            app.OttimizzarelastrategiaButtonGroup = uibuttongroup(app.UIFigure);
            app.OttimizzarelastrategiaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OttimizzarelastrategiaButtonGroupSelectionChanged, true);
            app.OttimizzarelastrategiaButtonGroup.Title = 'Ottimizzare la strategia?';
            app.NoButton = uiradiobutton(app.OttimizzarelastrategiaButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.SButton = uiradiobutton(app.OttimizzarelastrategiaButtonGroup);
            app.SButton.Text = 'Sì';
            app.PortafoglioInizialeEditFieldLabel = uilabel(app.UIFigure);
            app.PortafoglioInizialeEditFieldLabel.Text = 'Portafoglio Iniziale';
            app.PortafoglioInizialeEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortafoglioInizialeEditField.ValueDisplayFormat = '%.0f';
            app.PortafoglioInizialeEditField.Value = 1000000;
            app.Label = uilabel(app.UIFigure);
            app.Label.Enable = 'off';
            app.Label.Text = {'La strategia sarà ottimizzata testando'; 'i valori di input da 1 al parametro scelto '; '(Ad esempio da 1 a 8, da 1 a 30)'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ProfitLossLabel = uilabel(app.UIFigure);
            app.ProfitLossLabel.Text = 'ProfitLoss';
            app.InteresseLabel = uilabel(app.UIFigure);
            app.InteresseLabel.Text = 'Interesse';
            app.VolatilitPortafoglioLabel = uilabel(app.UIFigure);
            app.VolatilitPortafoglioLabel.Text = 'Volatilità Portafoglio';
            app.QuantitdiOrdiniLabel = uilabel(app.UIFigure);
            app.QuantitdiOrdiniLabel.Text = 'Quantità di Ordini';
            app.MassimoDrawDownLabel = uilabel(app.UIFigure);
            app.MassimoDrawDownLabel.Text = '% Massimo DrawDown';
            app.QuantitordinidiBuyLabel = uilabel(app.UIFigure);
            app.QuantitordinidiBuyLabel.Text = 'Quantità ordini di Buy';
            app.profitLossdiOrdiniinBuyLabel = uilabel(app.UIFigure);
            app.profitLossdiOrdiniinBuyLabel.Text = 'profitLoss di Ordini in Buy';
            app.QuantitordiniinSellLabel = uilabel(app.UIFigure);
            app.QuantitordiniinSellLabel.Text = 'Quantità ordini in Sell';
            app.SharpeRatioLabel = uilabel(app.UIFigure);
            app.SharpeRatioLabel.Text = 'Sharpe Ratio';
            app.profitLossdiOrdiniinSellLabel = uilabel(app.UIFigure);
            app.profitLossdiOrdiniinSellLabel.Text = 'profitLoss di Ordini in Sell';
            app.MissingDataLabel = uilabel(app.UIFigure);
            app.MissingDataLabel.Text = 'Missing Data';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Profit/Loss')
            xlabel(app.UIAxes, 'Tempo')
            ylabel(app.UIAxes, 'Valore')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Returns')
            xlabel(app.UIAxes_2, 'Tempo')
            ylabel(app.UIAxes_2, '% Returns')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Serie Temporale')
            xlabel(app.UIAxes_3, 'Tempo')
            ylabel(app.UIAxes_3, 'Prezzo')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backtestMVPApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end